classdef shulte_table_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Table                           matlab.ui.container.Tab
        GridLayoutTablePage             matlab.ui.container.GridLayout
        GridLayoutTableElement          matlab.ui.container.GridLayout
        TableLabel                      matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Lamp                            matlab.ui.control.Lamp
        GridLayoutTimeAndButtons        matlab.ui.container.GridLayout
        ResetButton                     matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        ResultArea                      matlab.ui.control.TextArea
        TimeLabel                       matlab.ui.control.Label
        PlotTab                         matlab.ui.container.Tab
        PlotGridLayout                  matlab.ui.container.GridLayout
        GridLayoutSessionsFiltering     matlab.ui.container.GridLayout
        SortMax                         matlab.ui.control.Button
        SortMin                         matlab.ui.control.Button
        SortSymbols                     matlab.ui.control.Button
        SortOrder                       matlab.ui.control.Button
        SortDate                        matlab.ui.control.Button
        SortSize                        matlab.ui.control.Button
        SortId                          matlab.ui.control.Button
        FilterMax                       matlab.ui.control.EditField
        FilterMin                       matlab.ui.control.EditField
        FilterSymbols                   matlab.ui.control.EditField
        FilterOrder                     matlab.ui.control.EditField
        FilterDate                      matlab.ui.control.EditField
        FilterSize                      matlab.ui.control.EditField
        FilterId                        matlab.ui.control.EditField
        SessionsInfoTable               matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        OptionsTab                      matlab.ui.container.Tab
        GridLayoutOptions               matlab.ui.container.GridLayout
        ShulteTableViewButtonGroup      matlab.ui.container.ButtonGroup
        AlphabetButton                  matlab.ui.control.RadioButton
        HexadimalButton                 matlab.ui.control.RadioButton
        DecimalButton                   matlab.ui.control.RadioButton
        ZeroStartCheckBox               matlab.ui.control.CheckBox
        ReverseOrderCheckBox            matlab.ui.control.CheckBox
        UpperCaseLettersCheckBox        matlab.ui.control.CheckBox
        RowStripingCheckBox             matlab.ui.control.CheckBox
        TableBackgroundColorPicker      matlab.ui.control.ColorPicker
        TableBackgroundFontColorPicker  matlab.ui.control.ColorPicker
        BackgroundColorColorPickerLabel  matlab.ui.control.Label
        FontColorPicker                 matlab.ui.control.ColorPicker
        FontColorColorPickerLabel       matlab.ui.control.Label
        FontItalicButton                matlab.ui.control.StateButton
        FontAngleLabel                  matlab.ui.control.Label
        FontWeightLabel                 matlab.ui.control.Label
        FontBoldButton                  matlab.ui.control.StateButton
        FontNameDropDown                matlab.ui.control.DropDown
        FontNameDropDownLabel           matlab.ui.control.Label
        FontSizeEditField               matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel          matlab.ui.control.Label
        ComplexitySpinner               matlab.ui.control.Spinner
        ComplexitySpinnerLabel          matlab.ui.control.Label
        InfoTab                         matlab.ui.container.Tab
        GridLayoutInfo                  matlab.ui.container.GridLayout
        InfoTable                       matlab.ui.control.Table
        TrainingPlanTab                 matlab.ui.container.Tab
        GridLayoutPlan                  matlab.ui.container.GridLayout
        PlanHTML                        matlab.ui.control.HTML
    end

    
    properties (Constant)
        dateFormat = 'yyyy-MM-dd'
    end

    properties (Access = private)
        options (1,1) model.Options
        shulteTable model.ShulteTable
        trainingTimer timer
        elapsedTimer controller.ElapsedTimer
        nextNumber uint32
        typoSoundEffect double
        typoSoundEffectSampleRate (1,1) double

        beginTime (1,1) datetime
        taskDurations model.TaskDuration
        mistakes (1,1) uint32

        storedSessions (1, :) model.SessionInfo
    end

    methods (Static)

        function state = fontToButtonState(fontSetting)
            switch fontSetting
                case 'normal'
                    state = false;
                case {'bold', 'italic'}
                    state = true;
            end
        end

        function value = buttonStateToFontWeight(state)
            switch state
                case false
                    value = 'normal';
                case true
                    value = 'bold';
            end
        end

        function value = buttonStateToFontAngle(state)
            switch state
                case false
                    value = 'normal';
                case true
                    value = 'italic';
            end
        end

    end
    
    methods (Access = private)

        function initEmptyTable(app)
            app.TimeLabel.Text = "-- : --";
            app.beginTime = datetime('now');

            app.shulteTable = model.ShulteTable.template(app.options.complexity);
            app.setShulteTableOptions();
            app.setInitialShulteTable();
        end
        
        function initTable(app)
            app.shulteTable = model.ShulteTable.create(app.options.complexity);
            app.setShulteTableOptions();
            app.setInitialShulteTable()
        end

        function setShulteTableOptions(app)
            import model.ShulteTableOrder;

            app.shulteTable.setView(app.options.shulteTableView);

            orderProperties = model.ShulteTableOrder.empty;
            if app.options.reverseOrder
                orderProperties(end + 1) = model.ShulteTableOrder.ReverseOrder;
            end
            if app.options.zeroStartIndexing
                orderProperties(end + 1) = model.ShulteTableOrder.NumericStartZero;
            end
            if app.options.lettersUpperCase
                orderProperties(end + 1) = model.ShulteTableOrder.UpperCaseLetters;
            end

            app.shulteTable.setOrder(orderProperties);
        end

        function setInitialShulteTable(app)
            data = app.shulteTable.getData();

            app.UITable.Data = data;
            app.UITable.ColumnWidth = 'fit';

            app.UITable.Selection = [];

            app.setTableLabel();
        end

        function app = switchLamp(app, state)
            switch (state)
                case "on"
                    app.Lamp.Color = controller.hex2rgb("#1d8e49");
                case "off"
                    app.Lamp.Color = controller.hex2rgb("#808080");
            end
        end

        function app = stopTrainingTimer(app)
            if ~isempty(app.trainingTimer) && isa(app.trainingTimer, 'timer') && isvalid(app.trainingTimer)
                stop(app.trainingTimer);
                delete(app.trainingTimer);
            end
        end

        function app = startTrainingTimer(app)
            app.stopTrainingTimer();

            app.trainingTimer = timer;
            app.trainingTimer.ExecutionMode = 'fixedSpacing';
            app.trainingTimer.Period = 1;
            app.trainingTimer.TimerFcn = @(src, event) app.updateTime();

            app.elapsedTimer = controller.ElapsedTimer();
            app.elapsedTimer.start();
            app.trainingTimer.start();
        end

        function app = updateTime(app)
            elapsed = app.elapsedTimer.getElapsed();
            [minutes, seconds] = controller.toMinutesAndSecconds(elapsed);
            if app.mistakes > 0
                app.TimeLabel.Text = sprintf("%.0f sec\n%02d : %02d\n%d err", ...
                    elapsed, minutes, seconds, app.mistakes);
            else
                app.TimeLabel.Text = sprintf("%.0f sec\n%02d : %02d", ...
                    elapsed, minutes, seconds);
            end
        end

        function app = loadAssets(app)
            soundFile = fullfile(model.Config.typoSoundEffect{:});
            if exist(soundFile, 'file')
                [app.typoSoundEffect, app.typoSoundEffectSampleRate] = audioread(soundFile);
            else
                uialert(app.UIFigure, ['File not found: ' soundFile], 'Missing File');
            end
        end

        function app = incorrectrWarn(app)
            sound(app.typoSoundEffect, app.typoSoundEffectSampleRate);
        end

        function app = onCompleteTask(app)
            app.elapsedTimer.pause();
            app.stopTrainingTimer();
            
            duration = app.elapsedTimer.getElapsed();
            elapsedTime = minutes(datetime('now') - app.beginTime);

            app.ResultArea.Value{end + 1} = sprintf('%03.1f s; %d err', duration, app.mistakes);
            app.switchLamp('on');
            app.taskDurations(:, end + 1) = model.TaskDuration(elapsedTime, duration, app.mistakes);

            app.mistakes = 0;
        end

        function app = setOptionsPage(app, options)
            arguments
                app shulte_table_ui
                options model.Options
            end
            app.ComplexitySpinner.Value = double(options.complexity);

            import model.ShulteTableView;
            switch(options.shulteTableView)
                case ShulteTableView.NumericDecimal
                    app.ShulteTableViewButtonGroup.SelectedObject = app.DecimalButton;
                case ShulteTableView.NumericHexedimal
                    app.ShulteTableViewButtonGroup.SelectedObject = app.HexadimalButton;
                case ShulteTableView.Alphabet
                    app.ShulteTableViewButtonGroup.SelectedObject = app.AlphabetButton;
            end

            app.ZeroStartCheckBox.Value = options.zeroStartIndexing;
            app.ReverseOrderCheckBox.Value = options.reverseOrder;
            app.UpperCaseLettersCheckBox.Value = options.lettersUpperCase;

            app.FontNameDropDown.Items = listfonts;
            app.FontNameDropDown.Value = options.fontName;
            app.FontSizeEditField.Value = options.fontSize;
            app.FontBoldButton.Value = shulte_table_ui.fontToButtonState(options.fontWeight);
            app.FontItalicButton.Value = shulte_table_ui.fontToButtonState(options.fontAngle);
            app.FontColorPicker.Value = options.fontColor;
            app.TableBackgroundFontColorPicker.Value = options.backgroundFontColor;
            app.TableBackgroundColorPicker.Value = options.backgroundColor;

            app.options = options;
        end

        function app = storeOptions(app)
            app.options.storeOptions();
        end
        
        function app = selectPreviousCell(app)
            if app.shulteTable.nextNumber == 0
                app.UITable.Selection = [];
                return
            end

            app.UITable.Selection = app.shulteTable.currentCell();
        end

        function storeToDatabase(app)
            if numel(app.taskDurations) == 0
                return
            end

            session = model.SessionResult( ...
                0, ...
                app.beginTime, ...
                app.beginTime + minutes(app.taskDurations(end).elapsed), ...
                app.shulteTable.size, ...
                app.shulteTable.orderProperties, ...
                app.shulteTable.viewProperty, ...
                app.taskDurations);

            database.storeSessionToDatabase(session);
        end
        
        function setTableApearence(app)
            app.UITable.FontName = app.options.fontName;
            app.UITable.FontSize = app.options.fontSize;
            app.UITable.FontWeight = app.options.fontWeight;
            app.UITable.FontAngle = app.options.fontAngle;
            app.UITable.ForegroundColor = app.options.fontColor;
            app.UITable.BackgroundColor = [app.options.backgroundFontColor; app.options.backgroundColor];
            app.UITable.RowStriping = app.options.tableRowStriping;
        end

        function setTableLabel(app)
            import model.ShulteTableView;
            import model.ShulteTableOrder;
            
            text = cell(1, 4);

            text(1) = {sprintf('Size: %dx%d', app.shulteTable.size, app.shulteTable.size)};
            
            if app.shulteTable.viewProperty == ShulteTableView.NumericDecimal
                text(2) = {'Symbols: Decimal'};
            elseif app.shulteTable.viewProperty == ShulteTableView.NumericHexedimal
                text(2) = {'Symbols: Hexedimal'};
            else
                text(2) = {'Symbols: Alphabet'};
            end

            if (app.shulteTable.viewProperty == ShulteTableView.NumericDecimal ...
                    || app.shulteTable.viewProperty == ShulteTableView.NumericHexedimal) ...
                    && any(app.shulteTable.orderProperties == ShulteTableOrder.NumericStartZero)
                if any(app.shulteTable.orderProperties == model.ShulteTableOrder.ReverseOrder)
                    text(3) = {'End with Zero symbol'};
                else
                    text(3) = {'Start from Zero symbol'};
                end
            end 

            [from, to] = app.shulteTable.getFromToTuple();
            if any(app.shulteTable.orderProperties == ShulteTableOrder.ReverseOrder)
                text(4) = {sprintf('Reverse: %s -- %s', from, to)};
            else
                text(4) = {sprintf('Forward: %s -- %s', from, to)};
            end 

            text(cellfun(@(x) isempty(x), text)) = [];
            app.TableLabel.Text = join(text, newline);
        end
        
        function resetTable(app)
            app.stopTrainingTimer();
            app.taskDurations(:) = [];
            app.initEmptyTable();
            app.ResultArea.Value = '';
            app.mistakes = 0;
        end
        
        function initPlotTab(app)
            app.setSessionsInfoTable();
            app.SessionsInfoTable.Selection = 1;

            sessionId = app.SessionsInfoTable.Data(app.SessionsInfoTable.Selection, 1);
            app.plotSession(sessionId);
        end

        function setSessionsInfoTable(app)
            app.storedSessions = database.getSessionsInfo(1000);
            data = cell(numel(app.storedSessions) + 1, 7);
            data(1, :) = {
                'current', ...
                sprintf('%dx%d', app.shulteTable.size, app.shulteTable.size), ...
                char(string(app.beginTime, app.dateFormat)), ...
                model.ShulteTableOrder.orderPropertiesToString(app.shulteTable.viewProperty, app.shulteTable.orderProperties), ...
                app.shulteTable.viewProperty.briefName, ...
                sprintf('%.1f', min([app.taskDurations.duration])), ...
                sprintf('%.1f', max([app.taskDurations.duration]))
                };

            for k = 1 : numel(app.storedSessions)
                app.storedSessions(k);
                data(k + 1, :) = {
                    app.storedSessions(k).id, ...
                    app.storedSessions(k).size, ...
                    char(string(app.storedSessions(k).beginAt, app.dateFormat)), ...
                    model.ShulteTableOrder.orderPropertiesToString(app.storedSessions(k).view, app.storedSessions(k).orderProperties), ...
                    app.storedSessions(k).view.briefName, ...
                    sprintf('%.1f', app.storedSessions(k).minDuration), ...
                    sprintf('%.1f', app.storedSessions(k).maxDuration)
                };
            end
            app.SessionsInfoTable.Data = data;
            app.SessionsInfoTable.UserData = data;
        end

        function plotSession(app, sessionId)
            if strcmp(sessionId, 'current')
                session = model.Session();
                session.beginAt = app.beginTime;
                session.complexity = app.shulteTable.size;

                app.plotTaskDurations(app.taskDurations);
            else
                session = database.getSession(sessionId);

                app.plotTaskDurations(session.taskDurations);
            end
        end

        function plotTaskDurations(app, taskDurations)
            if isempty(taskDurations)
                cla(app.UIAxes);
            else
                plot(app.UIAxes, [taskDurations.elapsed], [taskDurations.duration], 'k.-');
            end
        end

        function initInfoTab(app)
            app.InfoTable.ColumnName = model.Config.targetCompletionTimesColumns;
            app.InfoTable.Data = model.Config.targetCompletionTimes;
            completionTimes = database.getLastCompletionTime();
            if isempty(completionTimes)
                return
            end

            targetInfoData = model.TargetInfoData(model.Config.targetCompletionTimes, completionTimes);

            data = targetInfoData.merge();
            app.InfoTable.Data = data;
        end
        
        function sortSessionsTable(app)
            idOrder = model.SortingOrder.symbolToSortingOrder(app.SortId.Text);
            sizeOrder = model.SortingOrder.symbolToSortingOrder(app.SortSize.Text);
            dateOrder = model.SortingOrder.symbolToSortingOrder(app.SortDate.Text);
            optionsOrder = model.SortingOrder.symbolToSortingOrder(app.SortOrder.Text);
            symbolsOrder = model.SortingOrder.symbolToSortingOrder(app.SortSymbols.Text);
            minOrder = model.SortingOrder.symbolToSortingOrder(app.SortMin.Text);
            maxOrder = model.SortingOrder.symbolToSortingOrder(app.SortMax.Text);


            data = app.SessionsInfoTable.Data;

            [~, indices] = sort(cellfun(@(x) model.uint32DigitWeight(x, idOrder), data(:, 1)));
            data = data(indices, :);

            [~, indices] = sort(cellfun(@(x) model.sizeWeight(x, sizeOrder), data(:, 2)));
            data = data(indices, :);

            [~, indices] = sort(cellfun(@(x) model.dateWeight(x, dateOrder), data(:, 3)));
            data = data(indices, :);

            switch optionsOrder
                case model.SortingOrder.Ascend
                    [~, indices] = sortrows(data(:, 4), 'ascend');
                    data = data(indices, :);
                case model.SortingOrder.Descend
                    [~, indices] = sortrows(data(:, 4), 'descend');
                    data = data(indices, :);
            end

            switch symbolsOrder
                case model.SortingOrder.Ascend
                    [~, indices] = sortrows(data(:, 5), 'ascend');
                    data = data(indices, :);
                case model.SortingOrder.Descend
                    [~, indices] = sortrows(data(:, 5), 'descend');
                    data = data(indices, :);
            end

            [~, indices] = sort(cellfun(@(x) model.secondsWeight(x, minOrder), data(:, 6)));
            data = data(indices, :);

            [~, indices] = sort(cellfun(@(x) model.secondsWeight(x, maxOrder), data(:, 7)));
            data = data(indices, :);

            app.SessionsInfoTable.Data = data;
        end
        
        function filterSessionsTable(app)
            idFilter = app.FilterId.Value;
            sizeFilter = app.FilterSize.Value;
            dateFilter = app.FilterDate.Value;
            orderFilter = app.FilterOrder.Value;
            symbolsFilter = app.FilterSymbols.Value;
            minFilter = app.FilterMin.Value;
            maxFilter = app.FilterMax.Value;

            data = app.SessionsInfoTable.UserData;

            if ~isempty(idFilter)
                indices = startsWith(data(:, 1), idFilter, 'IgnoreCase',  true);
                data = data(indices, :);
            end

            if ~isempty(sizeFilter)
                indices = startsWith(data(:, 2), sizeFilter, 'IgnoreCase',  true);
                data = data(indices, :);
            end

            if ~isempty(dateFilter)
                indices = startsWith(data(:, 3), dateFilter, 'IgnoreCase',  true);
                data = data(indices, :);
            end

            if ~isempty(orderFilter)
                indices = contains(data(:, 4), orderFilter, 'IgnoreCase',  true);
                data = data(indices, :);
            end

            if ~isempty(symbolsFilter)
                indices = startsWith(data(:, 5), symbolsFilter, 'IgnoreCase',  true);
                data = data(indices, :);
            end

            if ~isempty(minFilter)
                indices = startsWith(data(:, 6), minFilter, 'IgnoreCase',  true);
                data = data(indices, :);
            end

            if ~isempty(maxFilter)
                indices = startsWith(data(:, 7), maxFilter, 'IgnoreCase',  true);
                data = data(indices, :);
            end

            app.SessionsInfoTable.Data = data;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            database.initDatabase();

            app.options = app.options.loadOptions();
            app.setOptionsPage(app.options);
            app.loadAssets();

            app.initEmptyTable();
            app.switchLamp("off");
            app.setTableApearence();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.initTable();
            app.startTrainingTimer();
            app.switchLamp('off');
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            if app.shulteTable.isCompleted()
                return
            end

            selection = num2cell(app.UITable.Selection);

            if app.shulteTable.isExpecedCell(selection)
                app.shulteTable.next(selection);
            else
                app.incorrectrWarn();
                app.selectPreviousCell();
                app.mistakes = app.mistakes + 1;
            end

            if app.shulteTable.isCompleted()
                app.onCompleteTask();
            end
        end

        % Callback function: ComplexitySpinner, ReverseOrderCheckBox, 
        % ...and 3 other components
        function ShulteTableComplexityChanged(app, event)
            import model.ShulteTableView;

            value = app.ComplexitySpinner.Value;
            app.options.complexity = value;

            switch(app.ShulteTableViewButtonGroup.SelectedObject.Tag)
                case 'NumericDecimal'
                    app.options.shulteTableView = ShulteTableView.NumericDecimal;
                case 'NumericHexedimal'
                    app.options.shulteTableView = ShulteTableView.NumericHexedimal;
                case 'Alphabet'
                    app.options.shulteTableView = ShulteTableView.Alphabet;
            end

            app.options.zeroStartIndexing = app.ZeroStartCheckBox.Value;
            app.options.reverseOrder = app.ReverseOrderCheckBox.Value;
            app.options.lettersUpperCase = app.UpperCaseLettersCheckBox.Value;            
            app.options.storeOptions();

            app.storeToDatabase();
            app.resetTable();


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetTable();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.storeToDatabase();
            
            delete(app);
        end

        % Value changed function: FontBoldButton, FontColorPicker, 
        % ...and 6 other components
        function ApearenceChanged(app, event)
            app.options.fontName = app.FontNameDropDown.Value;
            app.options.fontSize = app.FontSizeEditField.Value;
            app.options.fontWeight = shulte_table_ui.buttonStateToFontWeight(app.FontBoldButton.Value);
            app.options.fontAngle = shulte_table_ui.buttonStateToFontAngle(app.FontItalicButton.Value);

            app.options.fontColor = app.FontColorPicker.Value;
            app.options.backgroundFontColor = app.TableBackgroundFontColorPicker.Value;
            app.options.backgroundColor = app.TableBackgroundColorPicker.Value;
            app.options.tableRowStriping = app.RowStripingCheckBox.Value;

            app.setTableApearence();

            app.options.storeOptions();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Tag, 'PLOT')
                app.initPlotTab();
            elseif strcmp(selectedTab.Tag, 'INFO')
                app.initInfoTab();
            elseif strcmp(selectedTab.Tag, 'TABLE') && app.shulteTable.state == model.State.InProgress
                app.UITable.focus;
            end
        end

        % Selection changed function: SessionsInfoTable
        function SessionsInfoTableSelectionChanged(app, event)
            selection = app.SessionsInfoTable.Selection;
            sessionIdCell = app.SessionsInfoTable.Data(selection, 1);
            sessionId = sessionIdCell{1};
            app.plotSession(sessionId);
        end

        % Button pushed function: SortDate, SortId, SortMax, SortMin, 
        % ...and 3 other components
        function SortSessionsTableButtonPushed(app, event)
            arguments
                app 
                event matlab.ui.eventdata.ButtonPushedData
            end
            sortingOrder = model.SortingOrder.symbolToSortingOrder(event.Source.Text);
            nextSortingOrder = sortingOrder.next();
            event.Source.Text = nextSortingOrder.sortingSymbol;

            app.filterSessionsTable();
            app.sortSessionsTable();
        end

        % Value changed function: FilterDate, FilterId, FilterMax, 
        % ...and 4 other components
        function FilterSessionsTableValueChanged(app, event)
            app.filterSessionsTable();
            app.sortSessionsTable();            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.Table.Tag = 'TABLE';
            app.GridLayoutTablePage = uigridlayout(app.Table);
            app.GridLayoutTimeAndButtons = uigridlayout(app.GridLayoutTablePage);
            app.TimeLabel = uilabel(app.GridLayoutTimeAndButtons);
            app.TimeLabel.Text = 'Time';
            app.ResultArea = uitextarea(app.GridLayoutTimeAndButtons);
            app.NextButton = uibutton(app.GridLayoutTimeAndButtons, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ResetButton = uibutton(app.GridLayoutTimeAndButtons, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GridLayoutTableElement = uigridlayout(app.GridLayoutTablePage);
            app.Lamp = uilamp(app.GridLayoutTableElement);
            app.UITable = uitable(app.GridLayoutTableElement);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.TableLabel = uilabel(app.GridLayoutTableElement);
            app.TableLabel.Text = 'Label2';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PlotTab.Tag = 'PLOT';
            app.PlotGridLayout = uigridlayout(app.PlotTab);
            app.UIAxes = uiaxes(app.PlotGridLayout);
            title(app.UIAxes, 'Productivity')
            xlabel(app.UIAxes, 'Time, min')
            ylabel(app.UIAxes, 'Task Duration, sec')
            zlabel(app.UIAxes, 'Z')
            app.SessionsInfoTable = uitable(app.PlotGridLayout);
            app.SessionsInfoTable.ColumnName = {'ID'; 'Size'; 'Date'; 'Order'; 'Symbols'; 'Min'; 'Max'};
            app.SessionsInfoTable.RowName = {};
            app.SessionsInfoTable.SelectionType = 'row';
            app.SessionsInfoTable.SelectionChangedFcn = createCallbackFcn(app, @SessionsInfoTableSelectionChanged, true);
            app.SessionsInfoTable.Multiselect = 'off';
            app.GridLayoutSessionsFiltering = uigridlayout(app.PlotGridLayout);
            app.FilterId = uieditfield(app.GridLayoutSessionsFiltering, 'text');
            app.FilterId.ValueChangedFcn = createCallbackFcn(app, @FilterSessionsTableValueChanged, true);
            app.FilterSize = uieditfield(app.GridLayoutSessionsFiltering, 'text');
            app.FilterSize.ValueChangedFcn = createCallbackFcn(app, @FilterSessionsTableValueChanged, true);
            app.FilterDate = uieditfield(app.GridLayoutSessionsFiltering, 'text');
            app.FilterDate.ValueChangedFcn = createCallbackFcn(app, @FilterSessionsTableValueChanged, true);
            app.FilterOrder = uieditfield(app.GridLayoutSessionsFiltering, 'text');
            app.FilterOrder.ValueChangedFcn = createCallbackFcn(app, @FilterSessionsTableValueChanged, true);
            app.FilterSymbols = uieditfield(app.GridLayoutSessionsFiltering, 'text');
            app.FilterSymbols.ValueChangedFcn = createCallbackFcn(app, @FilterSessionsTableValueChanged, true);
            app.FilterMin = uieditfield(app.GridLayoutSessionsFiltering, 'text');
            app.FilterMin.ValueChangedFcn = createCallbackFcn(app, @FilterSessionsTableValueChanged, true);
            app.FilterMax = uieditfield(app.GridLayoutSessionsFiltering, 'text');
            app.FilterMax.ValueChangedFcn = createCallbackFcn(app, @FilterSessionsTableValueChanged, true);
            app.SortId = uibutton(app.GridLayoutSessionsFiltering, 'push');
            app.SortId.ButtonPushedFcn = createCallbackFcn(app, @SortSessionsTableButtonPushed, true);
            app.SortId.Text = '⇅';
            app.SortSize = uibutton(app.GridLayoutSessionsFiltering, 'push');
            app.SortSize.ButtonPushedFcn = createCallbackFcn(app, @SortSessionsTableButtonPushed, true);
            app.SortSize.Text = '⇅';
            app.SortDate = uibutton(app.GridLayoutSessionsFiltering, 'push');
            app.SortDate.ButtonPushedFcn = createCallbackFcn(app, @SortSessionsTableButtonPushed, true);
            app.SortDate.Text = '⇅';
            app.SortOrder = uibutton(app.GridLayoutSessionsFiltering, 'push');
            app.SortOrder.ButtonPushedFcn = createCallbackFcn(app, @SortSessionsTableButtonPushed, true);
            app.SortOrder.Text = '⇅';
            app.SortSymbols = uibutton(app.GridLayoutSessionsFiltering, 'push');
            app.SortSymbols.ButtonPushedFcn = createCallbackFcn(app, @SortSessionsTableButtonPushed, true);
            app.SortSymbols.Text = '⇅';
            app.SortMin = uibutton(app.GridLayoutSessionsFiltering, 'push');
            app.SortMin.ButtonPushedFcn = createCallbackFcn(app, @SortSessionsTableButtonPushed, true);
            app.SortMin.Text = '⇅';
            app.SortMax = uibutton(app.GridLayoutSessionsFiltering, 'push');
            app.SortMax.ButtonPushedFcn = createCallbackFcn(app, @SortSessionsTableButtonPushed, true);
            app.SortMax.Text = '⇅';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.GridLayoutOptions = uigridlayout(app.OptionsTab);
            app.ComplexitySpinnerLabel = uilabel(app.GridLayoutOptions);
            app.ComplexitySpinnerLabel.Text = 'Complexity';
            app.ComplexitySpinner = uispinner(app.GridLayoutOptions);
            app.ComplexitySpinner.Limits = [2 40];
            app.ComplexitySpinner.ValueChangedFcn = createCallbackFcn(app, @ShulteTableComplexityChanged, true);
            app.ComplexitySpinner.Tooltip = {'Adjust table complexity by changing grid size'};
            app.ComplexitySpinner.Value = 5;
            app.FontSizeEditFieldLabel = uilabel(app.GridLayoutOptions);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.GridLayoutOptions, 'numeric');
            app.FontSizeEditField.Limits = [4 200];
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.FontSizeEditField.Value = 18;
            app.FontNameDropDownLabel = uilabel(app.GridLayoutOptions);
            app.FontNameDropDownLabel.Text = 'Font Name';
            app.FontNameDropDown = uidropdown(app.GridLayoutOptions);
            app.FontNameDropDown.Items = {'Calibri', 'Arial', 'Consolas'};
            app.FontNameDropDown.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.FontNameDropDown.Value = 'Calibri';
            app.FontBoldButton = uibutton(app.GridLayoutOptions, 'state');
            app.FontBoldButton.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.FontBoldButton.Text = 'Bolt';
            app.FontWeightLabel = uilabel(app.GridLayoutOptions);
            app.FontWeightLabel.Text = 'Font Weight';
            app.FontAngleLabel = uilabel(app.GridLayoutOptions);
            app.FontAngleLabel.Text = 'Font Angle';
            app.FontItalicButton = uibutton(app.GridLayoutOptions, 'state');
            app.FontItalicButton.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.FontItalicButton.Text = 'Italic';
            app.FontColorColorPickerLabel = uilabel(app.GridLayoutOptions);
            app.FontColorColorPickerLabel.Text = 'Font Color';
            app.FontColorPicker = uicolorpicker(app.GridLayoutOptions);
            app.FontColorPicker.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.BackgroundColorColorPickerLabel = uilabel(app.GridLayoutOptions);
            app.BackgroundColorColorPickerLabel.Text = 'Background Color';
            app.TableBackgroundFontColorPicker = uicolorpicker(app.GridLayoutOptions);
            app.TableBackgroundFontColorPicker.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.TableBackgroundFontColorPicker.Tooltip = {'Row background color'};
            app.TableBackgroundColorPicker = uicolorpicker(app.GridLayoutOptions);
            app.TableBackgroundColorPicker.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.TableBackgroundColorPicker.Tooltip = {'Stripped row background color'};
            app.RowStripingCheckBox = uicheckbox(app.GridLayoutOptions);
            app.RowStripingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.RowStripingCheckBox.Text = 'Row Striping';
            app.UpperCaseLettersCheckBox = uicheckbox(app.GridLayoutOptions);
            app.UpperCaseLettersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShulteTableComplexityChanged, true);
            app.UpperCaseLettersCheckBox.Tooltip = {'Display hexadecimal letters A–F in uppercase'};
            app.UpperCaseLettersCheckBox.Text = 'Uppercase letters';
            app.ReverseOrderCheckBox = uicheckbox(app.GridLayoutOptions);
            app.ReverseOrderCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShulteTableComplexityChanged, true);
            app.ReverseOrderCheckBox.Text = 'Reverse Order';
            app.ZeroStartCheckBox = uicheckbox(app.GridLayoutOptions);
            app.ZeroStartCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShulteTableComplexityChanged, true);
            app.ZeroStartCheckBox.Tooltip = {'Start table numbering at 0 instead of 1'};
            app.ZeroStartCheckBox.Text = 'Zero Start';
            app.ShulteTableViewButtonGroup = uibuttongroup(app.GridLayoutOptions);
            app.ShulteTableViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShulteTableComplexityChanged, true);
            app.ShulteTableViewButtonGroup.Title = 'Shulte Table View';
            app.DecimalButton = uiradiobutton(app.ShulteTableViewButtonGroup);
            app.DecimalButton.Tag = 'NumericDecimal';
            app.DecimalButton.Text = 'Decimal';
            app.DecimalButton.Value = true;
            app.HexadimalButton = uiradiobutton(app.ShulteTableViewButtonGroup);
            app.HexadimalButton.Tag = 'NumericHexedimal';
            app.HexadimalButton.Text = 'Hexadimal';
            app.AlphabetButton = uiradiobutton(app.ShulteTableViewButtonGroup);
            app.AlphabetButton.Tag = 'Alphabet';
            app.AlphabetButton.Text = 'Alphabet';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.InfoTab.Tag = 'INFO';
            app.GridLayoutInfo = uigridlayout(app.InfoTab);
            app.InfoTable = uitable(app.GridLayoutInfo);
            app.InfoTable.ColumnName = {'Table Size'; 'Skill Level'; 'Target Time'; 'Last Reslt'; 'Avg. Search Time'};
            app.InfoTable.RowName = {};
            app.InfoTable.ColumnSortable = true;
            app.TrainingPlanTab = uitab(app.TabGroup);
            app.TrainingPlanTab.Title = 'Training Plan';
            app.GridLayoutPlan = uigridlayout(app.TrainingPlanTab);
            app.PlanHTML = uihtml(app.GridLayoutPlan);
            app.PlanHTML.HTMLSource = '+doc/training_plan.html';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shulte_table_ui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end